classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ProyectodeimgenesLabel  matlab.ui.control.Label
        VideoLabel              matlab.ui.control.Label
        CargarvideoButton       matlab.ui.control.Button
        FrameSpinner            matlab.ui.control.Spinner
        SimilaresSpinner        matlab.ui.control.Spinner
        IdentificarButton       matlab.ui.control.Button
        BasededatosLabel        matlab.ui.control.Label
        ImagenSpinner           matlab.ui.control.Spinner
        ImageLabel              matlab.ui.control.Label
        IdentificacionLabel     matlab.ui.control.Label
        SimilaresSpinnerLabel   matlab.ui.control.Label
        ImagenSpinnerLabel      matlab.ui.control.Label
        FrameSpinnerLabel       matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        VideoFrameDisplay       matlab.ui.control.UIAxes
        DatabaseDisplay         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)        
        % Función para calcular la diferencia entre dos histogramas
        % 0: Iguales
        % 1: Completamente diferentes
        function difference = calculateHistogramDifference(app, hist1, hist2)
            % Normalizar los histogramas
            hist1 = hist1 / sum(hist1);
            hist2 = hist2 / sum(hist2);
        
            % Calcular la similitud utilizando la fórmula de Bhattacharyya
            similitud = sum(sqrt(hist1 .* hist2));
        
            % Calcular la distancia de Hellinger
            difference = sqrt(1 - similitud);
        end

        function difference = normalizeHistogramDifference(app, frame1, frame2)
            difference = 1 - calculateHistogramDifference(app, frame1, frame2);
        end

        % Función de correlación entre descriptores HOG
        % Compara la similitud de dos imágenes basándose en la forma
        % general que tienen
        % -1: Relación negativa
        %  0: Sin relación
        %  1: Relación positiva
        function correlation = calculateCorrelation(app, hog1, hog2)
            % Calcular la correlación entre los descriptores
            correlation = corr(hog1', hog2');
        end

        function correlation = normalizeCorrelation(app, frame1, frame2) 
            correlation = abs(calculateCorrelation(app, frame1, frame2));
        end

        % Función para calcular la distancia coseno entre descriptores HOG
        % -1: Muy diferentes
        %  1: Muy similares
        function similarity = calculateHOGSimilarity(app, hog1, hog2)
            % Calcular el producto interno y las normas de los descriptores
            dotProduct = hog1 * hog2';
            normProduct = norm(hog1) * norm(hog2);
        
            % Calcular la similitud como el coseno del ángulo entre los descriptores
            similarity = dotProduct / normProduct;
        end

        function similarity = normalizeHOGSimilarity(app, frame1, frame2)
            s = calculateHOGSimilarity(app, frame1, frame2);
            similarity = (s + 1)/2;
        end

        function similarity = compareTextureWavelet(app, frame1, frame2)
            % Aplicar la transformada de wavelet a las imágenes
            [cA1, cH1, cV1, cD1] = dwt2(frame1, 'haar');
            [cA2, cH2, cV2, cD2] = dwt2(frame2, 'haar');
            
            % Calcular la similitud entre los coeficientes de wavelet
            similarity = mean([corr2(cA1, cA2), corr2(cH1, cH2), corr2(cV1, cV2), corr2(cD1, cD2)]);
        end
        
        function similarity = normalizeTextureWavelet(app, frame1, frame2)
            similarity = (compareTextureWavelet(app, frame1, frame2) + 1) / 2;
        end

        function predictedScene = classifyScene(frame, databaseFrames, databaseLabels, k)
            % Calcula las similitudes entre el frame de entrada y los frames de la base de datos
            similarities = zeros(size(databaseFrames, 1), 1);
            for i = 1:size(databaseFrames, 1)
                similarity = calculateSimilarity(frame, databaseFrames(i, :));
                similarities(i) = similarity;
            end
        
            % Ordena los índices de las similitudes en orden ascendente
            [~, sortedIndices] = sort(similarities);
        
            % Toma los k vecinos más cercanos
            kNearestLabels = databaseLabels(sortedIndices(1:k));
        
            % Realiza la votación mayoritaria para determinar la etiqueta de la escena
            predictedScene = mode(kNearestLabels);
        end
        
        function similarity = calculateSimilarity(app, frame1, frame2)
            resizedImage1 = imresize(frame1, [64 64]); % Ajusta el tamaño según tus necesidades
            resizedImage2 = imresize(frame2, [64 64]);

            grayFrame1 = rgb2gray(resizedImage1);
            grayFrame2 = rgb2gray(resizedImage2);

            hog1 = extractHOGFeatures(grayFrame1);
            hog2 = extractHOGFeatures(grayFrame2);

            % Calcula la similitud entre los frames utilizando alguna de las funciones de similitud que dispones, como calculateHistogramDifference, calculateCorrelation, calculateHOGSimilarity, etc.
            similarity = normalizeHOGSimilarity(app, hog1, hog2); % Aquí se utiliza la función normalizeHOGSimilarity como ejemplo
        end

        function predictedLabel = modeString(app, labels)
            % Encuentra la etiqueta más común en el vector de etiquetas
            uniqueLabels = unique(labels);
            counts = histcounts(categorical(labels), uniqueLabels);
            [~, maxCountIndex] = max(counts);
            predictedLabel = uniqueLabels(maxCountIndex);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            axis(app.DatabaseDisplay,'equal');
            axis(app.VideoFrameDisplay,'equal');
            set(app.DatabaseDisplay,'XColor', 'none','YColor','none')
            set(app.VideoFrameDisplay,'XColor', 'none','YColor','none')
            
            global imageDirs
            global imageLabels
            
            imageDirs = {};
            imageLabels = {};

            localDir = dir;
            
            fileCount = length(localDir);
            imageCount = 0;
            
            for i = 3 : fileCount
                if ~localDir(i).isdir
                    continue;
                end
            
                label = localDir(i).name;
            
                cd(strcat(localDir(i).folder, '/', localDir(i).name))
                iImageDir = dir;
                iImageCount = length(dir);

                for j = 3:iImageCount
                    imageUrl = strcat(localDir(i).folder, '/', localDir(i).name, '/', iImageDir(j).name);
                    imageCount = imageCount + 1;

                    imageDirs{imageCount} = imageUrl;
                    imageLabels{imageCount} = label;
                end
                
                cd ..
            end

            elijaVideo = imread('elijaunvideo.png');
            imshow(elijaVideo, 'Parent', app.VideoFrameDisplay);
            title(app.VideoFrameDisplay, '');

            firstImage = imread(imageDirs{1});
            imshow(firstImage, 'Parent', app.DatabaseDisplay);
            title(app.DatabaseDisplay, 'Imagen 1');

            app.ImageLabel.Text = imageLabels{1};

            app.ImagenSpinner.Limits = [1, length(imageDirs)];

        end

        % Value changed function: ImagenSpinner
        function changeDatabaseImage(app, event)
            global imageDirs
            global imageLabels

            value = app.ImagenSpinner.Value;
            
            firstImage = imread(imageDirs{value});
            imshow(firstImage, 'Parent', app.DatabaseDisplay);
            title(app.DatabaseDisplay, strcat('Imagen ', int2str(value)));

            app.ImageLabel.Text = imageLabels{value};
        end

        % Button pushed function: CargarvideoButton
        function loadVideo(app, event)
            [file,path] = uigetfile('*');

            if isequal(file,0)
               return
            end

            global video

            fullpath = strcat(path, file);
            video = VideoReader(fullpath);

            app.FrameSpinner.Limits = [1, video.NumFrames];
            app.IdentificarButton.Enable = "on";

            firstImage = read(video, 1);
            imshow(firstImage, 'Parent', app.VideoFrameDisplay);
            title(app.VideoFrameDisplay, 'Frame 1');
        end

        % Value changed function: FrameSpinner
        function changeFrame(app, event)
            value = app.FrameSpinner.Value;
           
            global video
            
            image = read(video, value);
            imshow(image, 'Parent', app.VideoFrameDisplay);
            title(app.VideoFrameDisplay, ['Frame ', int2str(value)]);
        end

        % Button pushed function: IdentificarButton
        function identificarFrame(app, event)
            global video
            global imageDirs
            global imageLabels

            imageCount = length(imageLabels);

            frame = read(video, app.FrameSpinner.Value);

            k = app.SimilaresSpinner.Value;
            
            % Calcula las similitudes entre el frame de entrada y los frames de la base de datos
            similarities = zeros(imageCount, 1);
            for i = 1:imageCount
                iImage = imread(imageDirs{i});

                similarity = calculateSimilarity(app, frame, iImage);
                similarities(i) = similarity;
            end
        
            % Ordena los índices de las similitudes en orden ascendente
            [~, sortedIndices] = sort(similarities);
        
            % Toma los k vecinos más cercanos
            kNearestLabels = imageLabels(sortedIndices(1:k));
        
            % Realiza la votación mayoritaria para determinar la etiqueta de la escena
            predictedScene = modeString(app, kNearestLabels);

            app.IdentificacionLabel.Text = strcat('El frame ', int2str(app.FrameSpinner.Value), 'es: ', predictedScene);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrameSpinnerLabel = uilabel(app.LeftPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.ImagenSpinnerLabel = uilabel(app.LeftPanel);
            app.ImagenSpinnerLabel.Text = 'Imagen';
            app.SimilaresSpinnerLabel = uilabel(app.LeftPanel);
            app.SimilaresSpinnerLabel.Text = 'Similares';
            app.IdentificacionLabel = uilabel(app.LeftPanel);
            app.IdentificacionLabel.Text = 'Elija un video primero';
            app.ImageLabel = uilabel(app.LeftPanel);
            app.ImageLabel.Text = 'Bosque?';
            app.ImagenSpinner = uispinner(app.LeftPanel);
            app.ImagenSpinner.ValueChangedFcn = createCallbackFcn(app, @changeDatabaseImage, true);
            app.BasededatosLabel = uilabel(app.LeftPanel);
            app.BasededatosLabel.Text = 'Base de datos';
            app.IdentificarButton = uibutton(app.LeftPanel, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @identificarFrame, true);
            app.IdentificarButton.Enable = 'off';
            app.IdentificarButton.Text = 'Identificar';
            app.SimilaresSpinner = uispinner(app.LeftPanel);
            app.SimilaresSpinner.Limits = [1 15];
            app.SimilaresSpinner.Value = 5;
            app.FrameSpinner = uispinner(app.LeftPanel);
            app.FrameSpinner.Limits = [1 1];
            app.FrameSpinner.ValueDisplayFormat = '%d';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @changeFrame, true);
            app.FrameSpinner.Value = 1;
            app.CargarvideoButton = uibutton(app.LeftPanel, 'push');
            app.CargarvideoButton.ButtonPushedFcn = createCallbackFcn(app, @loadVideo, true);
            app.CargarvideoButton.Text = 'Cargar video';
            app.VideoLabel = uilabel(app.LeftPanel);
            app.VideoLabel.Text = 'Video';
            app.ProyectodeimgenesLabel = uilabel(app.LeftPanel);
            app.ProyectodeimgenesLabel.Text = 'Proyecto de imágenes';
            app.RightPanel = uipanel(app.GridLayout);
            app.DatabaseDisplay = uiaxes(app.RightPanel);
            title(app.DatabaseDisplay, 'Title')
            xlabel(app.DatabaseDisplay, 'X')
            ylabel(app.DatabaseDisplay, 'Y')
            zlabel(app.DatabaseDisplay, 'Z')
            app.VideoFrameDisplay = uiaxes(app.RightPanel);
            title(app.VideoFrameDisplay, 'Title')
            xlabel(app.VideoFrameDisplay, 'X')
            ylabel(app.VideoFrameDisplay, 'Y')
            zlabel(app.VideoFrameDisplay, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end